classdef app_draft_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculatePSNRRatioButton      matlab.ui.control.Button
        PSNRRatioEditField            matlab.ui.control.NumericEditField
        ChooseFilterDropDown          matlab.ui.control.DropDown
        ChooseFilterDropDownLabel     matlab.ui.control.Label
        ByMYousufHammadYounusLabel    matlab.ui.control.Label
        ToDrGhulamJillaniAnsariLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        filteredImage                 matlab.ui.control.Image
        noisyImage                    matlab.ui.control.Image
        ChooseNoiseDropDown           matlab.ui.control.DropDown
        ChooseNoiseDropDownLabel      matlab.ui.control.Label
        ChooseImageButton             matlab.ui.control.Button
        inputImage                    matlab.ui.control.Image
    end

    methods (Access = private)
    
        %% GAUSSIAN NOISE

        function applyGaussianNoise(app)
        % Check if an image has been loaded
        if isempty(app.inputImage.ImageSource)
            uialert(app.UIFigure, 'Please load an image first.', 'No Image');
            return;
        end

        % Load the image
        img = imread(app.inputImage.ImageSource);

        % Convert to double for processing
        imgDouble = im2double(img);

        % Gaussian noise parameters
        meanVal = 0;
        variance = 0.03;
        sigma = sqrt(variance);

        % === Option 1: User-defined (Box-Muller method) ===
        [rows, cols, channels] = size(imgDouble);
        noisyImgScratch = imgDouble;

        for c = 1:channels
            u1 = rand(rows, cols);
            u2 = rand(rows, cols);
            z = sigma * sqrt(-2 * log(u1)) .* cos(2 * pi * u2) + meanVal;
            noisyImgScratch(:, :, c) = noisyImgScratch(:, :, c) + z;
        end

        % Clip values to [0, 1] range
        noisyImgScratch = min(max(noisyImgScratch, 0), 1);

        % Convert to uint8 for display
        noisyImgScratchDisplay = im2uint8(noisyImgScratch);

        % === Option 2: MATLAB built-in ===
        noisyImgBuiltIn = imnoise(img, 'gaussian', meanVal, variance);

        % === Choose which one to display ===
        useBuiltIn = true;  % <-- set this to false to use scratch method

        if useBuiltIn
            app.noisyImage.ImageSource = noisyImgBuiltIn;
        else
            app.noisyImage.ImageSource = noisyImgScratchDisplay;
        end
        end


    %% RAYLEIGH NOISE
    function applyRayleighNoise(app)
    % Check if image is loaded
    if isempty(app.inputImage.ImageSource)
        uialert(app.UIFigure, 'Please load an image first', 'No Image');
        return;
    end

    % Read image and convert to double
    img = imread(app.inputImage.ImageSource);
    img = im2double(img);  % Normalize image to [0,1]

    % Set Rayleigh distribution parameter
    b = 0.1;  % Scale parameter

    % === Scratch (user-defined) implementation ===
    rayleigh_noise_scratch = b * sqrt(-2 * log(1 - rand(size(img))));
    noisy_img_scratch = img + rayleigh_noise_scratch;
    noisy_img_scratch = min(noisy_img_scratch, 1);
    noisy_img_scratch = im2uint8(noisy_img_scratch);

    % === Built-in version (requires Statistics and Machine Learning Toolbox) ===
    try
        rayleigh_noise_builtin = raylrnd(b, size(img));
        noisy_img_builtin = img + rayleigh_noise_builtin;
        noisy_img_builtin = min(noisy_img_builtin, 1);
        noisy_img_builtin = im2uint8(noisy_img_builtin);

        % Choose which to display
        useBuiltIn = true;  % <--- Set to false to use manual version
        if useBuiltIn
            app.noisyImage.ImageSource = noisy_img_builtin;
        else
            app.noisyImage.ImageSource = noisy_img_scratch;
        end
    catch
        % Toolbox not available, fallback to manual
        app.noisyImage.ImageSource = noisy_img_scratch;
    end
    end

    %% ERLANG (GAMMA) NOISE

    function applyErlangNoise(app)
    if isempty(app.inputImage.ImageSource)
        uialert(app.UIFigure, 'Please load an image first', 'No Image');
        return;
    end

    img = imread(app.inputImage.ImageSource);
    img = im2double(img);  % Normalize for noise addition

    % Parameters for Erlang/Gamma
    k = 1;        % Shape (Erlang: integer)
    theta = 0.1;  % Scale

    % User-defined (scratch) method using sum of exponentials
    [rows, cols, ch] = size(img);
    gamma_noise_scratch = zeros(rows, cols, ch);
    for c = 1:ch
        for i = 1:k
            gamma_noise_scratch(:,:,c) = gamma_noise_scratch(:,:,c) + (-theta * log(rand(rows, cols)));
        end
    end
    noisy_img_scratch = img + gamma_noise_scratch;
    noisy_img_scratch = im2uint8(mat2gray(noisy_img_scratch));  % Rescale to uint8

    % Built-in method (requires Statistics Toolbox)
    try
        gamma_noise_builtin = gamrnd(k, theta, size(img));
        noisy_img_builtin = img + gamma_noise_builtin;
        noisy_img_builtin = im2uint8(mat2gray(noisy_img_builtin));
    catch
        noisy_img_builtin = noisy_img_scratch; % fallback
    end

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- Set to false to use scratch version
    if useBuiltIn
        app.noisyImage.ImageSource = noisy_img_builtin;
    else
        app.noisyImage.ImageSource = noisy_img_scratch;
    end
    end

    %% EXPONENTIAL NOISE

    function applyExponentialNoise(app)
    if isempty(app.inputImage.ImageSource)
        uialert(app.UIFigure, 'Please load an image first', 'No Image');
        return;
    end

    % Read and normalize image
    img = imread(app.inputImage.ImageSource);
    img = im2double(img);  % Normalize for noise addition

    lambda = 1.5;  % Rate parameter (mean = 1/lambda)

    % --- User-defined (scratch) method ---
    [rows, cols, ch] = size(img);
    exp_noise_scratch = zeros(rows, cols, ch);
    for c = 1:ch
        exp_noise_scratch(:,:,c) = -log(rand(rows, cols)) / lambda;
    end
    noisy_img_scratch = img + exp_noise_scratch;
    noisy_img_scratch = im2uint8(mat2gray(noisy_img_scratch));

    % --- Built-in method (if available) ---
    try
        exp_noise_builtin = exprnd(1/lambda, size(img));
        noisy_img_builtin = img + exp_noise_builtin;
        noisy_img_builtin = im2uint8(mat2gray(noisy_img_builtin));
    catch
        noisy_img_builtin = noisy_img_scratch;  % fallback
    end

    % === Choose which one to display ===
    useBuiltIn = true;  % Set to false to show scratch version
    if useBuiltIn
        app.noisyImage.ImageSource = noisy_img_builtin;
    else
        app.noisyImage.ImageSource = noisy_img_scratch;
    end
    end

    %% UNIFORM NOISE

    function applyUniformNoise(app)
    if isempty(app.inputImage.ImageSource)
        uialert(app.UIFigure, 'Please load an image first', 'No Image');
        return;
    end

    img = imread(app.inputImage.ImageSource);
    img = im2double(img);  % Normalize for noise addition

    % Parameters for Uniform noise
    a = -0.5;  % Lower bound of the uniform distribution
    b = 0.5;   % Upper bound of the uniform distribution

    % User-defined (scratch) method using uniform distribution
    [rows, cols, ch] = size(img);
    uniform_noise_scratch = zeros(rows, cols, ch);
    for c = 1:ch
        uniform_noise_scratch(:,:,c) = (b - a) * rand(rows, cols) + a;
    end
    noisy_img_scratch = img + uniform_noise_scratch;
    noisy_img_scratch = im2uint8(mat2gray(noisy_img_scratch));  % Rescale to uint8

    % Built-in method (if available)
    try
        uniform_noise_builtin = (b - a) * rand(rows, cols, ch) + a;
        noisy_img_builtin = img + uniform_noise_builtin;
        noisy_img_builtin = im2uint8(mat2gray(noisy_img_builtin));
    catch
        noisy_img_builtin = noisy_img_scratch; % fallback
    end

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- Set to false to use scratch version
    if useBuiltIn
        app.noisyImage.ImageSource = noisy_img_builtin;
    else
        app.noisyImage.ImageSource = noisy_img_scratch;
    end
    end

    %% IMPULSE (SALT AND PEPPER) NOISE


    function applyImpulseNoise(app)
    if isempty(app.inputImage.ImageSource)
        uialert(app.UIFigure, 'Please load an image first', 'No Image');
        return;
    end

    img = imread(app.inputImage.ImageSource);
    img = im2double(img);  % Normalize for noise addition

    % Parameters for Salt and Pepper noise
    noise_density = 0.05;  % Percentage of pixels to be corrupted by noise (salt + pepper)

    % Scratch version - manually adding salt and pepper noise
    [rows, cols, ch] = size(img);
    noisy_img_scratch = img;

    % Salt noise (white pixels)
    num_salt = round(noise_density * rows * cols);
    salt_indices = randperm(rows * cols, num_salt);
    [salt_rows, salt_cols] = ind2sub([rows, cols], salt_indices);
    for i = 1:num_salt
        noisy_img_scratch(salt_rows(i), salt_cols(i), :) = 1;  % Set to white (salt)
    end

    % Pepper noise (black pixels)
    num_pepper = round(noise_density * rows * cols);
    pepper_indices = randperm(rows * cols, num_pepper);
    [pepper_rows, pepper_cols] = ind2sub([rows, cols], pepper_indices);
    for i = 1:num_pepper
        noisy_img_scratch(pepper_rows(i), pepper_cols(i), :) = 0;  % Set to black (pepper)
    end

    noisy_img_scratch = im2uint8(mat2gray(noisy_img_scratch));  % Rescale to uint8

    % Built-in version - using imnoise function
    try
        noisy_img_builtin = imnoise(img, 'salt & pepper', noise_density);
        noisy_img_builtin = im2uint8(mat2gray(noisy_img_builtin));  % Rescale to uint8
    catch
        noisy_img_builtin = noisy_img_scratch;  % Fallback to scratch version if imnoise fails
    end

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- Set to false to use scratch version
    if useBuiltIn
        app.noisyImage.ImageSource = noisy_img_builtin;
    else
        app.noisyImage.ImageSource = noisy_img_scratch;
    end
    end


    %% MEAN FILTER

    function applyMeanFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Check if ImageSource is a file path or matrix
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    % Convert to double for filtering
    img = im2double(img);

    % Define Mean Filter kernel
    filterSize = 3;
    kernel = ones(filterSize, filterSize) / (filterSize^2);

    % User-defined (scratch) method
    [~, ~, ch] = size(img);
    filteredScratch = zeros(size(img));
    for c = 1:ch
        filteredScratch(:,:,c) = conv2(img(:,:,c), kernel, 'same');
    end
    filteredScratch = im2uint8(mat2gray(filteredScratch));

    % Built-in method using imfilter
    try
        filteredBuiltIn = imfilter(img, kernel, 'replicate');
        filteredBuiltIn = im2uint8(mat2gray(filteredBuiltIn));
    catch
        filteredBuiltIn = filteredScratch;
    end

    % === Choose which one to display ===
    useBuiltIn = true;
    if useBuiltIn
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end

    %% MEDIAN FILTER

    function applyMedianFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Load image (from file or matrix)
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    img = im2uint8(im2double(img));  % Normalize and ensure uint8

    % Set filter window size (must be odd for median)
    filterSize = 3;
    windowSize = [filterSize, filterSize];

    % === Built-in Median Filter ===
    try
        filteredBuiltIn = zeros(size(img), 'uint8');
        for c = 1:size(img, 3)
            filteredBuiltIn(:,:,c) = medfilt2(img(:,:,c), windowSize);
        end
    catch
        filteredBuiltIn = img;  % fallback
    end

    % === Scratch (User-defined) Median Filter ===
    padded = padarray(img, floor(windowSize/2), 'symmetric');
    filteredScratch = zeros(size(img), 'uint8');
    
    for c = 1:size(img, 3)
        for i = 1:size(img,1)
            for j = 1:size(img,2)
                block = padded(i:i+filterSize-1, j:j+filterSize-1, c);
                filteredScratch(i,j,c) = median(block(:));
            end
        end
    end

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- Flip to false to test scratch version
    if useBuiltIn
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end


    %% GAUSSIAN FILTER

    function applyGaussianFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Load image (from file or direct matrix)
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    img = im2double(img);  % Normalize for filtering

    % Parameters for Gaussian kernel
    filterSize = 5;  % e.g., 5x5
    sigma = 0.5;

    % === User-defined Gaussian kernel ===
    % Create Gaussian kernel manually
    halfSize = floor(filterSize / 2);
    [x, y] = meshgrid(-halfSize:halfSize, -halfSize:halfSize);
    gKernel = exp(-(x.^2 + y.^2) / (2 * sigma^2));
    gKernel = gKernel / sum(gKernel(:));  % Normalize

    % Apply convolution to each channel
    [~, ~, ch] = size(img);
    filteredScratch = zeros(size(img));
    for c = 1:ch
        filteredScratch(:,:,c) = conv2(img(:,:,c), gKernel, 'same');
    end
    filteredScratch = im2uint8(mat2gray(filteredScratch));

    % === Built-in version ===
    try
        % imgaussfilt only works on single-channel or color image
        filteredBuiltIn = imgaussfilt(img, sigma);
        filteredBuiltIn = im2uint8(mat2gray(filteredBuiltIn));
    catch
        filteredBuiltIn = filteredScratch;  % fallback
    end

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- change to false to use scratch version
    if useBuiltIn
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end

    %% WIENER FILTER (ADAPTIVE)

    function applyWienerFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Load image (from file or direct matrix)
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    img = im2double(img);  % Normalize for processing

    % Define filter window size
    windowSize = [5, 5];

    % === Built-in Wiener filter ===
    try
        filteredBuiltIn = zeros(size(img));
        for c = 1:size(img, 3)
            filteredBuiltIn(:, :, c) = wiener2(img(:, :, c), windowSize);
        end
        filteredBuiltIn = im2uint8(mat2gray(filteredBuiltIn));
    catch
        filteredBuiltIn = [];  % fallback if needed
    end

    % === User-defined Wiener Filter (simplified) ===
    % This version approximates local mean and variance
    filteredScratch = zeros(size(img));
    h = fspecial('average', windowSize);
    for c = 1:size(img, 3)
        localMean = imfilter(img(:,:,c), h, 'replicate');
        localVar = imfilter(img(:,:,c).^2, h, 'replicate') - localMean.^2;
        noiseVar = mean(localVar(:));  % assume noise is locally stationary

        filteredScratch(:,:,c) = localMean + ...
            ((max(localVar - noiseVar, 0)) ./ max(localVar, 1e-6)) .* (img(:,:,c) - localMean);
    end
    filteredScratch = im2uint8(mat2gray(filteredScratch));

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- set to false to use scratch version
    if useBuiltIn && ~isempty(filteredBuiltIn)
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end


    %% MIDPOINT FILTER

    function applyMidpointFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Load image (from file or direct matrix)
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    img = im2double(img);  % Normalize

    % Define window size
    windowSize = 3;
    padSize = floor(windowSize / 2);

    % === User-defined Midpoint Filter ===
    [rows, cols, ch] = size(img);
    paddedImg = padarray(img, [padSize padSize], 'symmetric');
    filteredScratch = zeros(size(img));

    for c = 1:ch
        for i = 1:rows
            for j = 1:cols
                region = paddedImg(i:i+2*padSize, j:j+2*padSize, c);
                minVal = min(region(:));
                maxVal = max(region(:));
                filteredScratch(i, j, c) = (minVal + maxVal) / 2;
            end
        end
    end
    filteredScratch = im2uint8(mat2gray(filteredScratch));

    % === No built-in version in MATLAB, so fallback ===
    filteredBuiltIn = filteredScratch;  % for consistency

    % === Choose which one to display ===
    useBuiltIn = true;  % No actual built-in; both same here
    if useBuiltIn
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end


    %% MIN FILTER


    function applyMinFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Load image (from file or matrix)
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    img = im2double(img);  % Normalize

    % Define filter window size
    windowSize = 3;
    se = true(windowSize);  % structuring element for ordfilt2
    padSize = floor(windowSize / 2);

    [rows, cols, ch] = size(img);
    filteredScratch = zeros(size(img));
    filteredBuiltIn = zeros(size(img));

    % === Apply filters channel-wise ===
    for c = 1:ch
        % === Built-in using ordfilt2 (min = order 1) ===
        filteredBuiltIn(:,:,c) = ordfilt2(img(:,:,c), 1, se, 'symmetric');

        % === User-defined version ===
        padded = padarray(img(:,:,c), [padSize padSize], 'symmetric');
        for i = 1:rows
            for j = 1:cols
                region = padded(i:i+2*padSize, j:j+2*padSize);
                filteredScratch(i,j,c) = min(region(:));
            end
        end
    end

    filteredBuiltIn = im2uint8(mat2gray(filteredBuiltIn));
    filteredScratch = im2uint8(mat2gray(filteredScratch));

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- Set to false to use user-defined version
    if useBuiltIn
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end


    %% MAX FILTER


    function applyMaxFilter(app)
    % Check if a noisy image exists
    if isempty(app.noisyImage.ImageSource)
        uialert(app.UIFigure, 'Please load a valid noisy image first.', 'No Noisy Image');
        return;
    end

    % Load image (from file or matrix)
    if ischar(app.noisyImage.ImageSource) || isstring(app.noisyImage.ImageSource)
        img = imread(app.noisyImage.ImageSource);
    else
        img = app.noisyImage.ImageSource;
    end

    img = im2double(img);  % Normalize for processing

    % Define filter window size
    windowSize = 3;
    se = true(windowSize);  % Structuring element
    padSize = floor(windowSize / 2);

    [rows, cols, ch] = size(img);
    filteredScratch = zeros(size(img));
    filteredBuiltIn = zeros(size(img));

    % === Apply filters channel-wise ===
    for c = 1:ch
        % === Built-in using ordfilt2 (max = order N) ===
        filteredBuiltIn(:,:,c) = ordfilt2(img(:,:,c), windowSize^2, se, 'symmetric');

        % === User-defined max filter ===
        padded = padarray(img(:,:,c), [padSize padSize], 'symmetric');
        for i = 1:rows
            for j = 1:cols
                region = padded(i:i+2*padSize, j:j+2*padSize);
                filteredScratch(i,j,c) = max(region(:));
            end
        end
    end

    filteredBuiltIn = im2uint8(mat2gray(filteredBuiltIn));
    filteredScratch = im2uint8(mat2gray(filteredScratch));

    % === Choose which one to display ===
    useBuiltIn = true;  % <-- Set to false to use scratch version
    if useBuiltIn
        app.filteredImage.ImageSource = filteredBuiltIn;
    else
        app.filteredImage.ImageSource = filteredScratch;
    end
    end

    %% PSNR

    function psnrValue = calculatePSNR(~, originalImg, denoisedImg)
    originalImg = im2double(originalImg);
    denoisedImg = im2double(denoisedImg);

    mse = mean((originalImg(:) - denoisedImg(:)).^2);

    if mse == 0
        psnrValue = Inf;
    else
        maxPixel = 1.0;  % im2double normalized
        psnrValue = 10 * log10((maxPixel^2) / mse);
    end
    end
    

end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function replaceInputImage(app, event)
            % Open file selection dialog
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'}, ...
                              'Select an Image');
    
            % If the user did not cancel
            if isequal(file, 0)
                return; % User canceled the dialog
            end
        
            % Read the image
            imgPath = fullfile(path, file);
            img = imread(imgPath);
            
            % Display the image on the UIAxes or Image component with ID inputImage
            app.inputImage.ImageSource = imgPath;  % for Image component
            % or if you're using an UIAxes instead:
            % imshow(img, 'Parent', app.inputImage);
        end

        % Value changed function: ChooseNoiseDropDown
        function changeNoise(app, event)
            value = app.ChooseNoiseDropDown.Value;
            switch value
                case 'Gaussian Noise'
                    app.applyGaussianNoise();

                case 'Rayleigh Noise'
                    app.applyRayleighNoise();

                case 'Erlang (Gamma) Noise'
                    app.applyErlangNoise();

                case 'Exponential Noise'
                    app.applyExponentialNoise();

                case 'Uniform Noise'
                    app.applyUniformNoise();

                case 'Impulse (Salt & Pepper) Noise'
                    app.applyImpulseNoise();     
                    
                otherwise
                    % Do nothing or reset to original
            end
            
            
        end

        % Value changed function: ChooseFilterDropDown
        function changeFilter(app, event)
            value = app.ChooseFilterDropDown.Value;
            switch value
                case 'Mean Filter'
                    app.applyMeanFilter();

                case 'Gaussian Filter'
                    app.applyGaussianFilter();

                case 'Wiener Filter (Adaptive)'
                    app.applyWienerFilter();

                case 'Midpoint Filter'
                    app.applyMidpointFilter();

                case 'Min Filter'
                    app.applyMinFilter();

                case 'Max Filter'
                    app.applyMaxFilter();     

                case 'Median Filter'
                    app.applyMedianFilter();  
                otherwise
                    % Do nothing or reset to original
            end
        end

        % Button pushed function: CalculatePSNRRatioButton
        function CalculatePSNRButtonPushed(app, event)


            % Load images from app
    originalPath = app.inputImage.ImageSource;
    filtered = app.filteredImage.ImageSource;

    if isempty(originalPath) || isempty(filtered)
        uialert(app.UIFigure, 'Please ensure both original and filtered images are loaded.', 'Missing Data');
        return;
    end

    % Read and normalize original image
    original = im2double(imread(originalPath));
    if size(original, 3) == 3
        original = rgb2gray(original);
    end

    % Normalize filtered image (already matrix)
    if size(filtered, 3) == 3
        filtered = rgb2gray(im2double(filtered));
    else
        filtered = im2double(filtered);
    end

    % Resize just in case
    filtered = imresize(filtered, size(original));

    % Compute PSNR
    mse = mean((original(:) - filtered(:)).^2);
    if mse == 0
        psnrVal = Inf;
    else
        psnrVal = 10 * log10(1 / mse);
    end

    % Display in your UI field (assuming a numeric or text field)
    app.PSNRRatioEditField.Value = str2double(sprintf('%.2f', psnrVal));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputImage = uiimage(app.UIFigure);
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @replaceInputImage, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.ChooseNoiseDropDownLabel = uilabel(app.UIFigure);
            app.ChooseNoiseDropDownLabel.Text = 'Choose Noise';
            app.ChooseNoiseDropDown = uidropdown(app.UIFigure);
            app.ChooseNoiseDropDown.Items = {'Select Noise', 'Gaussian Noise', 'Rayleigh Noise', 'Erlang (Gamma) Noise', 'Exponential Noise', 'Uniform Noise', 'Impulse (Salt & Pepper) Noise'};
            app.ChooseNoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @changeNoise, true);
            app.ChooseNoiseDropDown.Value = 'Select Noise';
            app.noisyImage = uiimage(app.UIFigure);
            app.filteredImage = uiimage(app.UIFigure);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Noise and Filters Analysis App'};
            app.ToDrGhulamJillaniAnsariLabel = uilabel(app.UIFigure);
            app.ToDrGhulamJillaniAnsariLabel.Text = 'To: Dr. Ghulam Jillani Ansari';
            app.ByMYousufHammadYounusLabel = uilabel(app.UIFigure);
            app.ByMYousufHammadYounusLabel.Text = 'By: M. Yousuf & Hammad Younus';
            app.ChooseFilterDropDownLabel = uilabel(app.UIFigure);
            app.ChooseFilterDropDownLabel.Text = 'Choose Filter';
            app.ChooseFilterDropDown = uidropdown(app.UIFigure);
            app.ChooseFilterDropDown.Items = {'Select Filter', 'Mean Filter', 'Median Filter', 'Gaussian Filter', 'Wiener Filter (Adaptive)', 'Midpoint Filter', 'Min Filter', 'Max Filter'};
            app.ChooseFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @changeFilter, true);
            app.ChooseFilterDropDown.Value = 'Select Filter';
            app.PSNRRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSNRRatioEditField.Tag = 'field';
            app.CalculatePSNRRatioButton = uibutton(app.UIFigure, 'push');
            app.CalculatePSNRRatioButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePSNRButtonPushed, true);
            app.CalculatePSNRRatioButton.Text = 'Calculate PSNR Ratio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_draft_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end